classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        MainmenuTab                    matlab.ui.container.Tab
        PidoptimizationSwitch          matlab.ui.control.RockerSwitch
        innerPIDonSwitch_2Label_2      matlab.ui.control.Label
        innerPIDonSwitch               matlab.ui.control.RockerSwitch
        innerPIDonSwitch_2Label        matlab.ui.control.Label
        DEditField_2                   matlab.ui.control.NumericEditField
        DEditField_4Label              matlab.ui.control.Label
        IEditField_2                   matlab.ui.control.NumericEditField
        IEditField_4Label              matlab.ui.control.Label
        PEditField_2                   matlab.ui.control.NumericEditField
        PEditField_4Label              matlab.ui.control.Label
        DEditField                     matlab.ui.control.NumericEditField
        DEditField_3Label              matlab.ui.control.Label
        IEditField                     matlab.ui.control.NumericEditField
        IEditField_3Label              matlab.ui.control.Label
        PEditField                     matlab.ui.control.NumericEditField
        PEditField_3Label              matlab.ui.control.Label
        LogSimmp4Button                matlab.ui.control.Button
        ChangeinnerPIDLabel_2          matlab.ui.control.Label
        ChangeouterPIDLabel_2          matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        Lamp_2Label                    matlab.ui.control.Label
        RunsimulationButton_2          matlab.ui.control.Button
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        ConfigTab                      matlab.ui.container.Tab
        Image                          matlab.ui.control.Image
        ResetButton                    matlab.ui.control.Button
        SaveconfigButton               matlab.ui.control.Button
        LoadConfigButton               matlab.ui.control.Button
        XplaneinmEditField_2Label_2    matlab.ui.control.Label
        TimeEditField                  matlab.ui.control.NumericEditField
        TimeEditFieldLabel             matlab.ui.control.Label
        PushEditField                  matlab.ui.control.NumericEditField
        PushEditFieldLabel             matlab.ui.control.Label
        SimulationTimesEditField       matlab.ui.control.NumericEditField
        SimulationTimesEditFieldLabel  matlab.ui.control.Label
        YplaneinmEditField             matlab.ui.control.NumericEditField
        YplaneinmEditFieldLabel        matlab.ui.control.Label
        XplaneinmEditField             matlab.ui.control.NumericEditField
        XplaneinmEditFieldLabel        matlab.ui.control.Label
        TssampletimeinsEditField       matlab.ui.control.NumericEditField
        TssampletimeinsEditFieldLabel  matlab.ui.control.Label
        VelocitymsEditField            matlab.ui.control.NumericEditField
        VelocitymsEditFieldLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin("base",'scooterparam');
            app.LogSimmp4Button.Visible="off";
        end

        % Value changed function: PEditField
        function PEditFieldValueChanged(app, event)
            value = app.PEditField.Value;
            assignin('base', 'outer_p',value); %change variable in workspace
        end

        % Value changed function: IEditField
        function IEditFieldValueChanged(app, event)
            value = app.IEditField.Value;
            assignin('base', 'outer_i',value);
        end

        % Value changed function: DEditField
        function DEditFieldValueChanged(app, event)
            value = app.DEditField.Value;
            assignin('base', 'outer_d',value);
        end

        % Button pushed function: RunsimulationButton_2
        function RunsimulationButtonPushed(app, event)
            evalin("base",'scooterparam');
            app.Lamp.Color=[1.00,1.00,0.00];
            app.LogSimmp4Button.Visible="off";
           % set_param('scootermodelsim','SimMechanicsOpenEditorOnUpdate','on')
            simOut=sim('scootermodelsim'); %start the simulation
            delete *.mp4;
            plot(app.UIAxes,simOut.steer_angle_CAD);
            plot(app.UIAxes2,simOut.roll_angle_CAD);
            app.Lamp.Color=[0.00,1.00,0.00];
            app.LogSimmp4Button.Visible="on";
        end

        % Value changed function: PEditField_2
        function PEditField_2ValueChanged(app, event)
            value = app.PEditField_2.Value;
            assignin('base', 'inner_p',value); 
        end

        % Value changed function: IEditField_2
        function IEditField_2ValueChanged(app, event)
            value = app.IEditField_2.Value;
           assignin('base', 'inner_i',value); 
        end

        % Value changed function: DEditField_2
        function DEditField_2ValueChanged(app, event)
            value = app.DEditField_2.Value;
            assignin('base', 'inner_d',value); 
        end

        % Value changed function: innerPIDonSwitch
        function innerPIDonSwitchValueChanged(app, event)
            value = app.innerPIDonSwitch.Value;
            assignin('base', 'PIDswitch',value);
        end

        % Button pushed function: LogSimmp4Button
        function LogSimmp4Button2Pushed(app, event)
           if(~isfile('C:\Users\pontus gustavsson\Desktop\Simulation_20221028\scootermodelsimvideo.mp4'))
            smwritevideo('scootermodelsim','scootermodelsimvideo.mp4');
           end
           app.LogSimmp4Button.Visible="off";
           % app.UIAxes2.Visible="on";
           % vidObj = VideoReader('C:\Users\pontus gustavsson\Desktop\Simulation_20221028\scootermodelsimvideo.mp4');
             %while(hasFrame(vidObj))
            %   frame = readFrame(vidObj);
            %   imshow(frame, 'Parent', app.UIAxes2);   % <--- use the Parent property
            %   pause(0.5/vidObj.FrameRate);
            %end
            %   app.UIAxes2.Visible="off";
        end

        % Value changed function: XplaneinmEditField
        function XplaneinmEditFieldValueChanged(app, event)
            value = app.XplaneinmEditField.Value;
            assignin('base', 'xPla',value);
        end

        % Value changed function: YplaneinmEditField
        function YplaneinmEditFieldValueChanged(app, event)
            value = app.YplaneinmEditField.Value;
           assignin('base', 'yPla',value);

        end

        % Value changed function: TssampletimeinsEditField
        function TssampletimeinsEditFieldValueChanged(app, event)
            value = app.TssampletimeinsEditField.Value;
            assignin('base', 'Ts',value);
        end

        % Value changed function: VelocitymsEditField
        function VelocitymsEditFieldValueChanged(app, event)
            value = app.VelocitymsEditField.Value;
            r_wheel=evalin("base",'r_wheel'); %gets r_wheel variables from scooterparam
            velocity=value/(r_wheel*3.6);
            assignin('base', 'initvelocity',velocity);

        end

        % Value changed function: SimulationTimesEditField
        function SimulationTimesEditFieldValueChanged(app, event)
            value = app.SimulationTimesEditField.Value;
            assignin('base', 'simulationtime',value);

        end

        % Value changed function: PushEditField
        function PushEditFieldValueChanged(app, event)
            value = app.PushEditField.Value;
           assignin('base', 'pushamp',value);

        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            value = app.TimeEditField.Value;
             assignin('base', 'phase',value);

        end

        % Button pushed function: LoadConfigButton
        function LoadConfigButtonPushed(app, event)
            Config=load("appconfig.mat");
           app.PEditField.Value= Config.outer_p;
           app.IEditField.Value= Config.outer_i;
           app.DEditField.Value= Config.outer_d;
           app.PEditField_2.Value= Config.inner_p ;
           app.IEditField_2.Value=Config.inner_i;
           app.DEditField_2.Value= Config.inner_d;
           app.innerPIDonSwitch.Value= Config.PIDswitch;
           app.VelocitymsEditField.Value= Config.v;
           app.SimulationTimesEditField.Value= Config.simulationtime;
           app.TssampletimeinsEditField.Value= Config.Ts;
           app.XplaneinmEditField.Value= Config.xPla;
           app.YplaneinmEditField.Value= Config.yPla;
           app.PushEditField.Value= Config.pushamp;
           app.TimeEditField.Value= Config.phase;
           assignin('base', 'oKp',Config.outer_p);
           assignin('base','oKi',Config.outer_i);
           assignin('base','oKd',Config.outer_d);
           assignin('base','iKp',Config.inner_p);
           assignin('base','iKi',Config.inner_i);
           assignin('base','iKd',Config.inner_d);
           assignin('base','PIDswitch',Config.PIDswitch);
           r_wheel=evalin("base",'r_wheel'); %gets r_wheel variables from scooterparam
           velocity=Config.v/(r_wheel*3.6);
           assignin('base','initvelocity',velocity);
           assignin('base','simulationtime',Config.simulationtime);
           assignin('base','Ts',Config.Ts);
           assignin('base','xPla',Config.xPla);
           assignin('base','yPla',Config.yPla);
           assignin('base','pushamp',Config.pushamp);
           assignin('base','phase',Config.phase);
        
        end

        % Button pushed function: SaveconfigButton
        function SaveconfigButtonPushed(app, event)
            outer_p = app.PEditField.Value;
            outer_i = app.IEditField.Value;
            outer_d = app.DEditField.Value;
            inner_p = app.PEditField_2.Value;
            inner_i = app.IEditField_2.Value;
            inner_d = app.DEditField_2.Value;
            PIDswitch = app.innerPIDonSwitch.Value;
            v = app.VelocitymsEditField.Value;
            simulationtime = app.SimulationTimesEditField.Value;
            Ts = app.TssampletimeinsEditField.Value;
            xPla = app.XplaneinmEditField.Value;
            yPla = app.YplaneinmEditField.Value;
            pushamp = app.PushEditField.Value;
            phase = app.TimeEditField.Value;
            save('appconfig.mat',"outer_p","outer_i","outer_d","inner_p","inner_i","inner_d","PIDswitch","v","simulationtime","Ts","xPla","yPla","pushamp","phase");
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            evalin("base",'scooterparam');
          app.PEditField.Value= evalin("base",'outer_p');
           app.IEditField.Value= evalin("base",'outer_i');
           app.DEditField.Value= evalin("base",'outer_d');
           app.PEditField_2.Value= evalin("base",'inner_p');
           app.IEditField_2.Value=evalin("base",'inner_i');
           app.DEditField_2.Value= evalin("base",'inner_d');
           app.innerPIDonSwitch.Value= evalin("base",'PIDswitch');
           app.VelocitymsEditField.Value= evalin("base",'v');
           app.SimulationTimesEditField.Value= evalin("base",'simulationtime');
           app.TssampletimeinsEditField.Value= evalin("base",'Ts');
           app.XplaneinmEditField.Value= evalin("base",'xPla');
           app.YplaneinmEditField.Value= evalin("base",'yPla');
           app.PushEditField.Value= evalin("base",'pushamp');
           app.TimeEditField.Value= evalin("base",'phase');
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
           %101,36,313,319
for i=1:320
    app.Image.Position(1)=app.Image.Position(1)+1;
    app.Image.Position(2)=app.Image.Position(2)-1;
     pause(0.01);
end
app.Image.Visible="off";
        end

        % Value changed function: PidoptimizationSwitch
        function PidoptimizationSwitchValueChanged(app, event)
            value = app.PidoptimizationSwitch.Value;
            assignin('base', 'Pidopton',value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainmenuTab = uitab(app.TabGroup);
            app.MainmenuTab.Title = 'Main menu';
            app.UIAxes = uiaxes(app.MainmenuTab);
            title(app.UIAxes, 'Steering angle')
            xlabel(app.UIAxes, 't (s)')
            ylabel(app.UIAxes, 'Steering angle (rad)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.MainmenuTab);
            title(app.UIAxes2, 'Roll angle')
            xlabel(app.UIAxes2, 't (s)')
            ylabel(app.UIAxes2, 'Roll angle (rad)')
            zlabel(app.UIAxes2, 'Z')
            app.RunsimulationButton_2 = uibutton(app.MainmenuTab, 'push');
            app.RunsimulationButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunsimulationButtonPushed, true);
            app.RunsimulationButton_2.Text = 'Run simulation             ';
            app.Lamp_2Label = uilabel(app.MainmenuTab);
            app.Lamp_2Label.Text = '';
            app.Lamp = uilamp(app.MainmenuTab);
            app.ChangeouterPIDLabel_2 = uilabel(app.MainmenuTab);
            app.ChangeouterPIDLabel_2.Text = 'Change outer PID';
            app.ChangeinnerPIDLabel_2 = uilabel(app.MainmenuTab);
            app.ChangeinnerPIDLabel_2.Text = 'Change inner PID';
            app.LogSimmp4Button = uibutton(app.MainmenuTab, 'push');
            app.LogSimmp4Button.ButtonPushedFcn = createCallbackFcn(app, @LogSimmp4Button2Pushed, true);
            app.LogSimmp4Button.Visible = 'off';
            app.LogSimmp4Button.Text = 'Log Sim(.mp4)';
            app.PEditField_3Label = uilabel(app.MainmenuTab);
            app.PEditField_3Label.Text = 'P';
            app.PEditField = uieditfield(app.MainmenuTab, 'numeric');
            app.PEditField.ValueChangedFcn = createCallbackFcn(app, @PEditFieldValueChanged, true);
            app.PEditField.Value = 4;
            app.IEditField_3Label = uilabel(app.MainmenuTab);
            app.IEditField_3Label.Text = 'I';
            app.IEditField = uieditfield(app.MainmenuTab, 'numeric');
            app.IEditField.ValueChangedFcn = createCallbackFcn(app, @IEditFieldValueChanged, true);
            app.IEditField.Value = 0.2;
            app.DEditField_3Label = uilabel(app.MainmenuTab);
            app.DEditField_3Label.Text = 'D';
            app.DEditField = uieditfield(app.MainmenuTab, 'numeric');
            app.DEditField.ValueChangedFcn = createCallbackFcn(app, @DEditFieldValueChanged, true);
            app.DEditField.Value = 0.2;
            app.PEditField_4Label = uilabel(app.MainmenuTab);
            app.PEditField_4Label.Text = 'P';
            app.PEditField_2 = uieditfield(app.MainmenuTab, 'numeric');
            app.PEditField_2.ValueChangedFcn = createCallbackFcn(app, @PEditField_2ValueChanged, true);
            app.PEditField_2.Value = 5;
            app.IEditField_4Label = uilabel(app.MainmenuTab);
            app.IEditField_4Label.Text = 'I';
            app.IEditField_2 = uieditfield(app.MainmenuTab, 'numeric');
            app.IEditField_2.ValueChangedFcn = createCallbackFcn(app, @IEditField_2ValueChanged, true);
            app.DEditField_4Label = uilabel(app.MainmenuTab);
            app.DEditField_4Label.Text = 'D';
            app.DEditField_2 = uieditfield(app.MainmenuTab, 'numeric');
            app.DEditField_2.ValueChangedFcn = createCallbackFcn(app, @DEditField_2ValueChanged, true);
            app.DEditField_2.Value = 1;
            app.innerPIDonSwitch_2Label = uilabel(app.MainmenuTab);
            app.innerPIDonSwitch_2Label.Text = {'inner'; 'PID on'};
            app.innerPIDonSwitch = uiswitch(app.MainmenuTab, 'rocker');
            app.innerPIDonSwitch.ItemsData = [0 1];
            app.innerPIDonSwitch.ValueChangedFcn = createCallbackFcn(app, @innerPIDonSwitchValueChanged, true);
            app.innerPIDonSwitch.Value = 0;
            app.innerPIDonSwitch_2Label_2 = uilabel(app.MainmenuTab);
            app.innerPIDonSwitch_2Label_2.Text = {'Pid '; 'optimization'};
            app.PidoptimizationSwitch = uiswitch(app.MainmenuTab, 'rocker');
            app.PidoptimizationSwitch.ItemsData = [0 1];
            app.PidoptimizationSwitch.ValueChangedFcn = createCallbackFcn(app, @PidoptimizationSwitchValueChanged, true);
            app.PidoptimizationSwitch.Value = 0;
            app.ConfigTab = uitab(app.TabGroup);
            app.ConfigTab.Title = 'Config';
            app.VelocitymsEditFieldLabel = uilabel(app.ConfigTab);
            app.VelocitymsEditFieldLabel.Text = 'Velocity(m/s)';
            app.VelocitymsEditField = uieditfield(app.ConfigTab, 'numeric');
            app.VelocitymsEditField.ValueChangedFcn = createCallbackFcn(app, @VelocitymsEditFieldValueChanged, true);
            app.VelocitymsEditField.Value = 8;
            app.TssampletimeinsEditFieldLabel = uilabel(app.ConfigTab);
            app.TssampletimeinsEditFieldLabel.Text = 'Ts(sample time in s)';
            app.TssampletimeinsEditField = uieditfield(app.ConfigTab, 'numeric');
            app.TssampletimeinsEditField.ValueChangedFcn = createCallbackFcn(app, @TssampletimeinsEditFieldValueChanged, true);
            app.TssampletimeinsEditField.Value = 0.01;
            app.XplaneinmEditFieldLabel = uilabel(app.ConfigTab);
            app.XplaneinmEditFieldLabel.Text = 'X(plane in m)';
            app.XplaneinmEditField = uieditfield(app.ConfigTab, 'numeric');
            app.XplaneinmEditField.ValueChangedFcn = createCallbackFcn(app, @XplaneinmEditFieldValueChanged, true);
            app.XplaneinmEditField.Value = 80;
            app.YplaneinmEditFieldLabel = uilabel(app.ConfigTab);
            app.YplaneinmEditFieldLabel.Text = 'Y(plane in m)';
            app.YplaneinmEditField = uieditfield(app.ConfigTab, 'numeric');
            app.YplaneinmEditField.ValueChangedFcn = createCallbackFcn(app, @YplaneinmEditFieldValueChanged, true);
            app.YplaneinmEditField.Value = 40;
            app.SimulationTimesEditFieldLabel = uilabel(app.ConfigTab);
            app.SimulationTimesEditFieldLabel.Text = 'Simulation Time(s)';
            app.SimulationTimesEditField = uieditfield(app.ConfigTab, 'numeric');
            app.SimulationTimesEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationTimesEditFieldValueChanged, true);
            app.SimulationTimesEditField.Value = 10;
            app.PushEditFieldLabel = uilabel(app.ConfigTab);
            app.PushEditFieldLabel.Text = 'Push';
            app.PushEditField = uieditfield(app.ConfigTab, 'numeric');
            app.PushEditField.ValueChangedFcn = createCallbackFcn(app, @PushEditFieldValueChanged, true);
            app.PushEditField.Value = 5;
            app.TimeEditFieldLabel = uilabel(app.ConfigTab);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.ConfigTab, 'numeric');
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.TimeEditField.Value = 2;
            app.XplaneinmEditField_2Label_2 = uilabel(app.ConfigTab);
            app.XplaneinmEditField_2Label_2.Text = 'Disturbance:';
            app.LoadConfigButton = uibutton(app.ConfigTab, 'push');
            app.LoadConfigButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigButtonPushed, true);
            app.LoadConfigButton.Text = 'Load Config';
            app.SaveconfigButton = uibutton(app.ConfigTab, 'push');
            app.SaveconfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveconfigButtonPushed, true);
            app.SaveconfigButton.Text = 'Save config';
            app.ResetButton = uibutton(app.ConfigTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.Image = uiimage(app.ConfigTab);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'scootgui.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end